classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GUIBandejaLabel            matlab.ui.control.Label
        Geometria                  matlab.ui.container.Panel
        ImageCuadrado              matlab.ui.control.Image
        ImageHexagono              matlab.ui.control.Image
        LadoCuadradommSliderLabel  matlab.ui.control.Label
        LadoCuadradoSlider         matlab.ui.control.Slider
        LadoHexagonommSliderLabel  matlab.ui.control.Label
        LadoHexagonoSlider         matlab.ui.control.Slider
        AlturammEditFieldLabel     matlab.ui.control.Label
        AlturammEditField          matlab.ui.control.EditField
        Pack1                      matlab.ui.container.Panel
        GeometraseleccionadaLabel  matlab.ui.control.Label
        CuadradoLabel              matlab.ui.control.Label
        HexagonoLabel              matlab.ui.control.Label
        SeleccionaelmaterialparatenerunaestimacindelamasaLabel  matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        HazclickenlaconfiguracinquedeseesLabel  matlab.ui.control.Label
        Material                   matlab.ui.container.Panel
        MATERIALESLabel            matlab.ui.control.Label
        PanelAluminio              matlab.ui.container.Panel
        ALUMINIO6061CheckBox       matlab.ui.control.CheckBox
        PropiedadesAluminio        matlab.ui.control.TextArea
        PanelCompuesto             matlab.ui.container.Panel
        CFRPCheckBox               matlab.ui.control.CheckBox
        PropiedadesCompuesto       matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Image clicked function: ImageCuadrado
        function ImageCuadradoClicked(app, event)
            app.LadoCuadradoSlider.Visible = 'on';
            app.LadoCuadradommSliderLabel.Visible = 'on';
            app.LadoHexagonoSlider.Visible = 'off';
            app.LadoHexagonommSliderLabel.Visible = 'off';
            app.Pack1.Visible = 'on';
            app.CuadradoLabel.Visible = 'on';
            app.HexagonoLabel.Visible = 'off';          
        end

        % Image clicked function: ImageHexagono
        function ImageHexagonoClicked(app, event)
            app.LadoCuadradoSlider.Visible = 'off';
            app.LadoCuadradommSliderLabel.Visible = 'off';
            app.LadoHexagonoSlider.Visible = 'on';
            app.LadoHexagonommSliderLabel.Visible = 'on';
            app.Pack1.Visible = 'on';
            app.CuadradoLabel.Visible = 'off';
            app.HexagonoLabel.Visible = 'on';
        end

        % Value changed function: ALUMINIO6061CheckBox
        function ALUMINIO6061CheckBoxValueChanged(app, event)
            value = app.ALUMINIO6061CheckBox.Value;
            
            switch value
                case 0
                app.CFRPCheckBox.Value = 1; 
                app.PropiedadesAluminio.Visible = 'off';
                app.PropiedadesCompuesto.Visible = 'on'; 
                case 1
                app.CFRPCheckBox.Value = 0; 
                app.PropiedadesAluminio.Visible = 'on';
                app.PropiedadesCompuesto.Visible = 'off'; 
            end
        end

        % Value changed function: CFRPCheckBox
        function CFRPCheckBoxValueChanged(app, event)
            value = app.CFRPCheckBox.Value;
            switch value
                case 0
                app.ALUMINIO6061CheckBox.Value = 1; 
                app.PropiedadesCompuesto.Visible = 'off';
                app.PropiedadesAluminio.Visible = 'on';
                case 1
                app.ALUMINIO6061CheckBox.Value = 0; 
                app.PropiedadesCompuesto.Visible = 'on';
                app.PropiedadesAluminio.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GUIBandejaLabel = uilabel(app.UIFigure);
            app.GUIBandejaLabel.Text = 'GUI Bandeja';
            app.Geometria = uipanel(app.UIFigure);
            app.ImageCuadrado = uiimage(app.Geometria);
            app.ImageCuadrado.ImageClickedFcn = createCallbackFcn(app, @ImageCuadradoClicked, true);
            app.ImageCuadrado.ImageSource = 'Cuadrada-Back.png';
            app.ImageHexagono = uiimage(app.Geometria);
            app.ImageHexagono.ImageClickedFcn = createCallbackFcn(app, @ImageHexagonoClicked, true);
            app.ImageHexagono.ImageSource = 'Hexagonal-Back.png';
            app.LadoCuadradommSliderLabel = uilabel(app.Geometria);
            app.LadoCuadradommSliderLabel.Visible = 'off';
            app.LadoCuadradommSliderLabel.Text = 'Lado Cuadrado [mm]';
            app.LadoCuadradoSlider = uislider(app.Geometria);
            app.LadoCuadradoSlider.Limits = [200 400];
            app.LadoCuadradoSlider.Visible = 'off';
            app.LadoCuadradoSlider.Value = 200;
            app.LadoHexagonommSliderLabel = uilabel(app.Geometria);
            app.LadoHexagonommSliderLabel.Visible = 'off';
            app.LadoHexagonommSliderLabel.Text = 'Lado Hexagono [mm]';
            app.LadoHexagonoSlider = uislider(app.Geometria);
            app.LadoHexagonoSlider.Limits = [200 300];
            app.LadoHexagonoSlider.Visible = 'off';
            app.LadoHexagonoSlider.Value = 200;
            app.AlturammEditFieldLabel = uilabel(app.Geometria);
            app.AlturammEditFieldLabel.Text = 'Altura [mm] :';
            app.AlturammEditField = uieditfield(app.Geometria, 'text');
            app.Pack1 = uipanel(app.Geometria);
            app.Pack1.Visible = 'off';
            app.GeometraseleccionadaLabel = uilabel(app.Pack1);
            app.GeometraseleccionadaLabel.Text = 'Geometría seleccionada: ';
            app.CuadradoLabel = uilabel(app.Pack1);
            app.CuadradoLabel.Text = 'CUADRADO';
            app.HexagonoLabel = uilabel(app.Pack1);
            app.HexagonoLabel.Text = 'HEXÁGONO';
            app.SeleccionaelmaterialparatenerunaestimacindelamasaLabel = uilabel(app.Geometria);
            app.SeleccionaelmaterialparatenerunaestimacindelamasaLabel.Text = 'Selecciona el material para tener una estimación de la masa:';
            app.EditField = uieditfield(app.Geometria, 'text');
            app.HazclickenlaconfiguracinquedeseesLabel = uilabel(app.Geometria);
            app.HazclickenlaconfiguracinquedeseesLabel.Text = 'Haz click en la configuración que desees';
            app.Material = uipanel(app.UIFigure);
            app.MATERIALESLabel = uilabel(app.Material);
            app.MATERIALESLabel.Text = 'MATERIALES';
            app.PanelAluminio = uipanel(app.Material);
            app.ALUMINIO6061CheckBox = uicheckbox(app.PanelAluminio);
            app.ALUMINIO6061CheckBox.ValueChangedFcn = createCallbackFcn(app, @ALUMINIO6061CheckBoxValueChanged, true);
            app.ALUMINIO6061CheckBox.Text = 'ALUMINIO 6061';
            app.PropiedadesAluminio = uitextarea(app.PanelAluminio);
            app.PropiedadesAluminio.Visible = 'off';
            app.PropiedadesAluminio.Value = {'Young Modulus : 6,89 * 10^10 N/m^2'; ''; 'Density: 2700 kg/m^3'; ''; 'Yield Strength: 2,76 * 10^8 N/m^2'; ''; 'Poisson Ratio: 0,33'};
            app.PanelCompuesto = uipanel(app.Material);
            app.CFRPCheckBox = uicheckbox(app.PanelCompuesto);
            app.CFRPCheckBox.ValueChangedFcn = createCallbackFcn(app, @CFRPCheckBoxValueChanged, true);
            app.CFRPCheckBox.Text = 'CFRP';
            app.PropiedadesCompuesto = uitextarea(app.PanelCompuesto);
            app.PropiedadesCompuesto.Visible = 'off';
            app.PropiedadesCompuesto.Value = {'Young Modulus : 2,1 * 10^11 N/m^2'; ''; 'Density: 1800 kg/m^3'; ''; 'Yield Strength: 3,80 * 10^9 N/m^2'; ''; 'Poisson Ratio: 0,02'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end